classdef appMaquiaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        RelatrioMenu              matlab.ui.container.Menu
        ExportarDadosMenu         matlab.ui.container.Menu
        TabelaMenu                matlab.ui.container.Menu
        yfxMenu                   matlab.ui.container.Menu
        GrficoMenu                matlab.ui.container.Menu
        yfxMenu_2                 matlab.ui.container.Menu
        PDFMenu                   matlab.ui.container.Menu
        JPEGMenu                  matlab.ui.container.Menu
        PNGMenu                   matlab.ui.container.Menu
        zfxyMenu_2                matlab.ui.container.Menu
        PDFMenuZ                  matlab.ui.container.Menu
        JPEGMenuZ                 matlab.ui.container.Menu
        PNGMenuZ                  matlab.ui.container.Menu
        CurvasMenu                matlab.ui.container.Menu
        PDFCurvasMenu             matlab.ui.container.Menu
        JPEGCurvasMenu            matlab.ui.container.Menu
        PNGCurvasMenu             matlab.ui.container.Menu
        SairMenu                  matlab.ui.container.Menu
        TabGroup                  matlab.ui.container.TabGroup
        tgFrVr                    matlab.ui.container.Tab
        pDados                    matlab.ui.container.Panel
        Panel                     matlab.ui.container.Panel
        yxLabel                   matlab.ui.control.Label
        eF                        matlab.ui.control.EditField
        aLabel                    matlab.ui.control.Label
        eA                        matlab.ui.control.NumericEditField
        eH                        matlab.ui.control.NumericEditField
        hLabel                    matlab.ui.control.Label
        eB                        matlab.ui.control.NumericEditField
        bLabel                    matlab.ui.control.Label
        IntervaloLabel            matlab.ui.control.Label
        TabGroup2                 matlab.ui.container.TabGroup
        DerivaoTab                matlab.ui.container.Tab
        pMetodos_2                matlab.ui.container.Panel
        MetodosNumericosLabel_2   matlab.ui.control.Label
        ProgressivaSwitch_2Label  matlab.ui.control.Label
        Progressiva2Switch        matlab.ui.control.Switch
        RegressivaSwitch_2Label   matlab.ui.control.Label
        Regressiva2Switch         matlab.ui.control.Switch
        DerivadaLabel             matlab.ui.control.Label
        D2DerivadaSwitch          matlab.ui.control.Switch
        DerivaoTab_2              matlab.ui.container.Tab
        pMetodos                  matlab.ui.container.Panel
        MetodosNumericosLabel     matlab.ui.control.Label
        ProgressivaSwitch_3Label  matlab.ui.control.Label
        Progressiva3Switch        matlab.ui.control.Switch
        RegressivaSwitch_3Label   matlab.ui.control.Label
        Regressiva3Switch         matlab.ui.control.Switch
        CentradaSwitchLabel       matlab.ui.control.Label
        Centrada3Switch           matlab.ui.control.Switch
        IntegraoTab               matlab.ui.container.Tab
        pMetodos_3                matlab.ui.container.Panel
        MetodosNumericosLabel_3   matlab.ui.control.Label
        TrapziosSwitch_2Label     matlab.ui.control.Label
        TrapziosSwitch            matlab.ui.control.Switch
        SimpsonSwitch_2Label      matlab.ui.control.Label
        SimpsonSwitch             matlab.ui.control.Switch
        AreaLabel                 matlab.ui.control.Label
        AreaSwitch                matlab.ui.control.Switch
        IntegralLabel             matlab.ui.control.Label
        eExata                    matlab.ui.control.NumericEditField
        eRegras                   matlab.ui.control.NumericEditField
        RegrasLabel               matlab.ui.control.Label
        DadosLabel                matlab.ui.control.Label
        bAtualizar                matlab.ui.control.Button
        UIMostrador               matlab.ui.control.UIAxes
        pResultados               matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
        tgFr2Vr                   matlab.ui.container.Tab
        pDados2v                  matlab.ui.container.Panel
        Panel2v                   matlab.ui.container.Panel
        fxyLabel                  matlab.ui.control.Label
        eFxy                      matlab.ui.control.EditField
        axLabel                   matlab.ui.control.Label
        eAx                       matlab.ui.control.NumericEditField
        hxLabel                   matlab.ui.control.Label
        eHx                       matlab.ui.control.NumericEditField
        bxLabel                   matlab.ui.control.Label
        eBx                       matlab.ui.control.NumericEditField
        ayLabel                   matlab.ui.control.Label
        eAy                       matlab.ui.control.NumericEditField
        hyLabel                   matlab.ui.control.Label
        eHy                       matlab.ui.control.NumericEditField
        byLabel                   matlab.ui.control.Label
        eBy                       matlab.ui.control.NumericEditField
        IntegralLabel_2           matlab.ui.control.Label
        eR2v                      matlab.ui.control.NumericEditField
        bAtualizar2v              matlab.ui.control.Button
        eHarmonica                matlab.ui.control.EditField
        OpesdeClculoKnobLabel     matlab.ui.control.Label
        bOpCalc                   matlab.ui.control.DiscreteKnob
        bOpGraf                   matlab.ui.control.Switch
        UIMostrador2v             matlab.ui.control.UIAxes
        pResultados2v             matlab.ui.container.Panel
        UIGrafico2v               matlab.ui.control.UIAxes
        UICNivel                  matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
                    
            function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y)
                %UNTITLED Summary of this function goes here
                %   Detailed explanation goes here
                %   29/04/2021  Arménio Correia armenioc@isec.pt
                x = a:h:b;
                n = length(x);
                if nargin == 5
                    y = f(x);
                end
                dydx = zeros(1,n);
                for i = 1:n-1
                    dydx(i) = (y(i+1)-y(i))/h;
                end
                dydx(n) = dydx(n-1);
                %dydx(n) = (y(n)-y(n-1))/h;
            end
            
        
            function YN = isHarmonica(~,f)
                syms z(x,y)
                if (diff(f,x,2)+diff(f,y,2)==0)
                    YN = 1;
                else
                    YN = 0;
                end
            end
        
            function S = RSimpson(~,f,a,b,n)
                h=(b-a)/n;
                x=a:h:b;
                s=0;
                
                for i=2:n-1
                    if mod(i,2)==0
                       s=s+2*f(x(i));
                    else
                       s=s+4*f(x(i));
                    end
                
                end
                
                S=h/3*(f(a)+s+f(b));
            
             end
             
        
            function results = sair(app)
                resposta=questdlg("Pretende Sair?","Sair","Sim","Não","Sim");
                if strcmp(resposta,"Sim")
                    delete(app.UIFigure);
                end
            end
            
            function integraldef = calcint(~,a,b,strF)
                clear x;
                f = @(x) eval(vectorize(strF));
                syms y(x);
                integraldef=double(int(f(x),x,a,b));
                disp(integraldef);
            end
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(x)
            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value; 
            h = app.eH.Value; 
            b = app.eB.Value; 
             
            erroFuncao=MException("MATLAB:Atualizar:badF",...
                                  "Introduza uma função em x");
            erroA=MException("MATLAB:Atualizar:badF",...
                             "Introduza um número a que seja real e escalar");
            
            erroB=MException("MATLAB:Atualizar:badF",...
                             "Introduza um número real b, com b > a");
            erroh=MException("MATLAB:Atualizar:badF",...
                             "Introduza um número real h, com h > 0");
            try
                
                try
                    fteste=f(x);
                    
                catch 
                    throw(erroFuncao)
                end
                
                %Verificação se a é um número real e escalar
                if (~(isscalar(a) && isreal(a)))
                    throw(erroA)
                end
                
                %Verificação se b é um número real, escalar e maior que a
                if (~(isscalar(b) && isreal(b) && b > a))
                    throw(erroB)
                end
                
                %Verificação se h é um número real e maior que 0
                if (~(isreal(h) && h > 0))
                    throw(erroh)
                end
                
                dydxSym = diff(f(x));  %%Diferencial da função
               if(~isreal(dydxSym))     %Se a derivada for diferente de uma constante
                    g =@(x) eval(vectorize(char(dydxSym)));                
                    [x,y,dydxDFP] = NDerivadaDFP(app,f,a,b,h);                
                    dydxExata = g(x);                
    
                    tabela1 = array2table([x.',y.',dydxExata.']);   %Tabela
                    
                    nColuna={'x',"y'", 'dydx'};
                    
                    plot(app.UIAxes,x,y,"-r+")       %Plot da funcao
                    hold(app.UIAxes,"on")
                    grid(app.UIAxes,'On')
                    plot(app.UIAxes,x, dydxExata, "-bo")    %plot da exata
                    legend(app.UIAxes,'y', 'Exata')
                    formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];  %Latex da exata
                    
                    if strcmp(app.D2DerivadaSwitch.Value,'On')      
                        clear x
                        syms y(x)
                        f = @(x) eval(vectorize(strF));
                        dydx2Sym = diff(f(x),2); 
                        if(~isreal(dydx2Sym))    %Se a 2 derivada nao for uma constante
                        
                            g =@(x) eval(vectorize(char(dydx2Sym)));
                            [x,y,dydxDFP] = NDerivadaDFP(app,f,a,b,h); 
                            dydx2Exata = g(x);
                            plot(app.UIAxes,x,dydx2Exata,'-g*');
                            
                            [x,y,dy2dx]=D2derivada(f,a,b,h);
                            plot(app.UIAxes,x,dy2dx,'-m>');
                            legend(app.UIAxes,'y', "y'", "y''",'DF2Derivada');
                            
                            errody2dx=abs(dydx2Exata-dy2dx);
                            tabela1 = array2table([x.',y.',dydxExata.', dydx2Exata.',dy2dx.',errody2dx.']);
                            nColuna={'x','y', 'dydx','d^2ydx^2','DF2Derivada','Erro DF2Derivada'};
                            
                           
                        end     %Latex da segunda derivada
                         formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'....','\frac{d^2y}{dx^2}=',latex(dydx2Sym),'$'];
                    end
                    
                    if (strcmp(app.Progressiva2Switch.Value,'On') || strcmp(app.Regressiva2Switch.Value,'On')) %Se for selecionado uma...
                                                                                                    %diferença finita 2 pontos
                        [x,y,dydxDFP] = NDerivadaDFP(app,f,a,b,h); 
                        erroDFP = abs(dydxExata -dydxDFP);
                        [x,y,dydxDFR2]=Regressiva2pontos(f,a,b,h,y);
                        erroDFR2 = abs(dydxExata -dydxDFR2);
                        prog2=plot(app.UIAxes,x,dydxDFP,"-g*");
                        regre2=plot(app.UIAxes,x,dydxDFR2,"-md");
                        legend(app.UIAxes,'y', 'Exata', 'DF2Progressiva', 'DF2Regressiva')
                        if strcmp(app.Progressiva2Switch.Value,'On')
                            set(prog2,'Visible','On')
                            tabela1 = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.']);
                            nColuna={'x','y', 'dydx','DF2Progressiva', 'Erro DF2P'};
                            
                        else
                            set(prog2,'Visible','Off')
                        end
                        if strcmp(app.Regressiva2Switch.Value,'On')
                            set(regre2,'Visible','On')
                            tabela1 = array2table([x.',y.',dydxExata.', dydxDFR2.',erroDFR2.']);
                            nColuna={'x','y', 'dydx','DF2Regressiva', 'Erro DF2R'};
                        else
                            set(regre2,'Visible','Off')
                        end
                        if  (strcmp(app.Progressiva2Switch.Value,'On') && strcmp(app.Regressiva2Switch.Value,'On')) % Se ambos tiverem ligados
                            
                            tabela1 = array2table([x.',y.',dydxExata.', dydxDFP.', dydxDFR2.',erroDFP.',erroDFR2.']);
                            nColuna={'x','y', 'dydx', 'DF2Progressiva','DF2Regressiva', 'Erro DF2P', 'Erro DF2R'};    
                            
                        end


                    elseif strcmp(app.Progressiva3Switch.Value,'On')||...   %Se um dos switchs das diferenças finitas de 3 pontos
                           strcmp(app.Regressiva3Switch.Value,'On') ||...   %estiverem ligados
                           strcmp(app.Centrada3Switch.Value,'On')
                       
                        [x,y,dydxDFP3]=Progressiva3pontos(f,a,b,h,y);
                        prog3=plot(app.UIAxes,x,dydxDFP3,"-m^");
                        [x,y,dydxDFR3]=Regressiva3pontos(f,a,b,h,y);
                        regre3=plot(app.UIAxes,x,dydxDFR3,"-c<");
                        [x,y,dydxDFC3]=Centrada3pontos(f,a,b,h,y);
                        cent3=plot(app.UIAxes,x,dydxDFC3,"-b>");
                        
                        erroDFP3=abs(dydxExata -dydxDFP3);
                        erroDFR3=abs(dydxExata -dydxDFR3);
                        erroDFC3= abs(dydxExata -dydxDFC3);
                       
                        
                        legend(app.UIAxes,'y', 'Exata', 'DF3Progressiva', 'DF3Regressiva','DF3Centrada')
                        
                        if strcmp(app.Progressiva3Switch.Value,'On') 
                            set(prog3,'Visible','On')
                            tabela1 = array2table([x.',y.',dydxExata.', dydxDFP3.',erroDFP3.']);
                            nColuna={'x','y', 'dydx','DF3Progressiva', 'Erro DF3P'};   
           
                              
                        else
                            set(prog3,'Visible','Off')
                        end
                        
                        if strcmp(app.Regressiva3Switch.Value,'On')
                            set(regre3,'Visible','On')    %Liga o grafico
                            tabela1 = array2table([x.',y.',dydxExata.', dydxDFR3.',erroDFR3.']);
                            nColuna={'x','y', 'dydx','DFRegressiva3', 'Erro DFR3'};
    
                              
                            if strcmp(app.Progressiva3Switch.Value,'On')
                                tabela1 = array2table([x.',y.',dydxExata.', dydxDFP3.',dydxDFR3.',erroDFP3.',erroDFR3.']);
                                nColuna={'x','y', 'dydx','DF3Progressiva','DF3Regressiva', 'Erro DFP3', 'Erro DFR3'};      
                            end
                        
                        else
                            set(regre3,'Visible','Off')
                        end


                        if strcmp(app.Centrada3Switch.Value,'On')
                            set(cent3,'Visible','On')
                            tabela1 = array2table([x.',y.',dydxExata.', dydxDFC3.',erroDFC3.']);
                            nColuna={'x','y', 'dydx','DF3Centrada',  'Erro DFC3'};
                            
                            if strcmp(app.Progressiva3Switch.Value,'On')
                                tabela1 = array2table([x.',y.',dydxExata.', dydxDFP3.',dydxDFC3.',erroDFP3.',erroDFC3.']);
                                nColuna={'x','y', 'dydx','DF3Progressiva','DF3Centrada', 'Erro DF3P', 'Erro DF3C'};   
                           
                            elseif strcmp(app.Regressiva3Switch.Value,'On')
                              tabela1 = array2table([x.',y.',dydxExata.', dydxDFR3.',dydxDFC3.',erroDFR3.',erroDFC3.']);
                              nColuna={'x','y', 'dydx','DF3Regressiva','DF3Centrada', 'Erro DF3R', 'Erro DF3C'};
                            
                            
                            end
                            
                          
                        else
                            set(cent3,'Visible','Off')
                        end
    
                        if strcmp(app.Progressiva3Switch.Value,'On') && strcmp(app.Centrada3Switch.Value,'On')...
                                && strcmp(app.Regressiva3Switch.Value,'On')
                            
                            tabela1 = array2table([x.',y.',dydxExata.', dydxDFP3.',dydxDFR3.',dydxDFC3.',erroDFP3.',erroDFR3.',erroDFC3.']);
                            nColuna={'x','y', 'dydx','DF3Progressiva','DF3Regressiva','DF3Centrada','Erro DF3P', 'Erro DF3R','Erro DF3C'};   
                        end


                    end 
               else   %Se a derivada for real
                   formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                   tabela1=[];
                   nColuna={};
                   cla(app.UIAxes)
                   hold(app.UIAxes,"on")
                   grid(app.UIAxes,'On')
                   [x,y,dydxDFP] = NDerivadaDFP(app,f,a,b,h); 
                   plot(app.UIAxes,x,y,"-b");
                   legend(app.UIAxes,'y')
               end
               
                if strcmp(app.SimpsonSwitch.Value,'On')||...
                       strcmp(app.TrapziosSwitch.Value,'On') ||...
                       strcmp(app.AreaSwitch.Value,'On')
                        
                        
                        n=(b-a)/h;
                        intsym = int(f(sym('x')));               
                        g =@(x) eval(vectorize(char(intsym)));                 
                        intExata = g(x);    
                        plot(app.UIAxes,x,intExata,"-c<");
                        areaInt=area(app.UIAxes,x,y,"FaceColor","r");  
                       
                        if(~isreal(dydxSym))
                            legend(app.UIAxes,'y', "y'",'Integral','Area')
                        else
                            legend(app.UIAxes,'y','Integral', 'Area')
                        
                        end

                        integraldef=calcint(app,a,b,strF);
                        app.eExata.Value=integraldef;


                    if strcmp(app.SimpsonSwitch.Value,'On')
                        S=RSimpson(app,f,a,b,n);
                        app.eRegras.Value=S;
                    end
                    
                    if strcmp(app.TrapziosSwitch.Value,'On')
                        T=RTrapezios(f,a,b,n);
                        app.eRegras.Value=T;
                    end
                    
                    if strcmp(app.AreaSwitch.Value,'On')
                        
                        set(areaInt,'Visible','On')
                    else
                        set(areaInt,'Visible','Off')
                    end
                    
                    str=str2sym(strF);
                    formaLatex =['$','\int','=',latex(intsym),'$'];
                    
                    sExata = int(f(sym('x')),a,b);
                    tabela1 = array2table([x.',y.',intExata.']);
                    nColuna={'x','y', 'Integral'};
                
                    
                end


                hold(app.UIAxes,"off")


                cla(app.UIMostrador)
                app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18)


                n=(b-a)/h;

                app.UITable.Data = tabela1;
                app.UITable.ColumnName = nColuna;
            catch ME
                errordlg(ME.message,"ERRO","modal")
            end


        end

        % Button pushed function: bAtualizar2v
        function bAtualizar2vButtonPushed(app, event)
            syms z(x,y)
            strFxy = app.eFxy.Value;
            f =@(x,y) eval(vectorize(strFxy));
            ax = app.eAx.Value; 
            hx = app.eHx.Value; 
            bx = app.eBx.Value;
            ay = app.eAy.Value; 
            hy = app.eHy.Value; 
            by = app.eBy.Value;
            [x,y] = meshgrid(ax:hx:bx, ay:hy:by);
            
            erroFuncao=MException("MATLAB:Atualizar:badF",...
                                  "Introduza uma função em x e y");
            erroA=MException("MATLAB:Atualizar:badF",...
                             "Introduza um número a que seja real e escalar");
            
            erroB=MException("MATLAB:Atualizar:badF",...
                             "Introduza um número real b, com b > a");
            
            erroh=MException("MATLAB:Atualizar:badF",...
                             "Introduza um número real h, com h > 0");
            
            try
                
                try
                    fteste = f(x,y);
                    
                catch 
                    throw(erroFuncao)
                end
                
                %Verificação se os parametros a de x e y são um número real e escalar
                if (~(isscalar(ax) && isreal(ax)))
                    throw(erroA)
                end
                
                if (~(isscalar(ay) && isreal(ay)))
                    throw(erroA)
                end
                
                %Verificação se os parametros b de x e y são um número real
                %, escalar e maiores que a de x e y respetivamente
                if (~(isscalar(bx) && isreal(bx) && bx > ax))
                    throw(erroB)
                end
                
                if (~(isscalar(by) && isreal(by) && by > ay))
                    throw(erroB)
                end
                
                %Verificação se h é um número real e maior que 0
                if (~(isreal(hx) && hx > 0))
                    throw(erroh)
                end
                
                if (~(isreal(hy) && hy > 0))
                    throw(erroh)
                end
                
                z = f(x,y);
                
                if strcmp(app.bOpCalc.Value,'Derivada ordem a x')
                    syms x y
                    f = str2sym(strFxy);
                    dfdxSym = diff(f,x);
    
                    formaLatex =['$','\frac{df}{dx}=',latex(dfdxSym),'$'];
                    
                    cla(app.UIMostrador2v)
                    app.UIMostrador2v.Toolbar.Visible = 'off';
                    text(app.UIMostrador2v,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                    
                end
                
                if strcmp(app.bOpCalc.Value,'Derivada ordem a y')
                    syms x y
                    f = str2sym(strFxy);
                    dfdxSym = diff(f,y);
    
                    formaLatex =['$','\frac{df}{dx}=',latex(dfdxSym),'$'];
                    
                    cla(app.UIMostrador2v)
                    app.UIMostrador2v.Toolbar.Visible = 'off';
                    text(app.UIMostrador2v,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                
                end
                
                if strcmp(app.bOpCalc.Value,'Integral duplo')
                    syms x y
                    f = str2sym(strFxy);
                    int2dxdy = int( int(f, x), y);
    
                    formaLatex =['$', '\int \int f(x, y) dx dy =',latex(int2dxdy),'$'];
                    
                    app.eR2v.Value = double(int( int(f, x, ax, bx), y, ay, by));
                    
                    cla(app.UIMostrador2v)
                    app.UIMostrador2v.Toolbar.Visible = 'off';
                    text(app.UIMostrador2v,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
                
                end
                                                
                if strcmp(app.bOpCalc.Value,'Gráficos')
                    if strcmp(app.bOpGraf.Value,'Malha')
                        mesh(app.UIGrafico2v, x,y,z)
                    else
                        surf(app.UIGrafico2v, x,y,z)
                    end
                    contour3(app.UICNivel, x,y,z)
                    cla(app.UIMostrador2v)
                end
                            
                if isHarmonica(app,f)
                    strHarmonica = "f é harmónica";
                    corHarmonica = "g";
                else
                    strHarmonica = "f não é harmónica";
                    corHarmonica = "r";                               
                end


                app.eHarmonica.Value = strHarmonica;
                app.eHarmonica.FontColor = corHarmonica;
           catch ME
                errordlg(ME.message,"ERRO","modal")
           end
        end

        % Value changed function: Progressiva2Switch
        function Progressiva2SwitchValueChanged(app, event)
            value = app.Progressiva2Switch.Value;
            if strcmp(value,'On')
                app.Progressiva3Switch.Value='Off';
                app.Regressiva3Switch.Value='Off';
                app.Centrada3Switch.Value='Off';
                app.TrapziosSwitch.Value='Off';
                app.SimpsonSwitch.Value='Off';
                app.AreaSwitch.Value='Off';
                app.D2DerivadaSwitch.Value='Off';
            end
        end

        % Value changed function: Regressiva2Switch
        function Regressiva2SwitchValueChanged(app, event)
            value = app.Regressiva2Switch.Value;
            if strcmp(value,'On')
                app.Progressiva3Switch.Value='Off';
                app.Regressiva3Switch.Value='Off';
                app.Centrada3Switch.Value='Off';
                app.TrapziosSwitch.Value='Off';
                app.SimpsonSwitch.Value='Off';
                app.AreaSwitch.Value='Off';
                app.D2DerivadaSwitch.Value='Off';
            end
        end

        % Value changed function: Progressiva3Switch
        function Progressiva3SwitchValueChanged(app, event)
            value = app.Progressiva3Switch.Value;
            if strcmp(value,'On')
                app.Progressiva2Switch.Value='Off';
                app.Regressiva2Switch.Value='Off';
                app.TrapziosSwitch.Value='Off';
                app.SimpsonSwitch.Value='Off';
                app.AreaSwitch.Value='Off';
                app.D2DerivadaSwitch.Value='Off';
            end
        end

        % Value changed function: Regressiva3Switch
        function Regressiva3SwitchValueChanged(app, event)
            value = app.Regressiva3Switch.Value;
            if strcmp(value,'On')
                app.Progressiva2Switch.Value='Off';
                app.Regressiva2Switch.Value='Off';
                app.TrapziosSwitch.Value='Off';
                app.SimpsonSwitch.Value='Off';
                app.AreaSwitch.Value='Off';
                app.D2DerivadaSwitch.Value='Off';
            end
        end

        % Value changed function: Centrada3Switch
        function Centrada3SwitchValueChanged(app, event)
            value = app.Centrada3Switch.Value;
            if strcmp(value,'On')
                app.Progressiva2Switch.Value='Off';
                app.Regressiva2Switch.Value='Off';
                app.TrapziosSwitch.Value='Off';
                app.SimpsonSwitch.Value='Off';
                app.AreaSwitch.Value='Off';
                app.D2DerivadaSwitch.Value='Off';
            end
        end

        % Value changed function: TrapziosSwitch
        function TrapziosSwitchValueChanged(app, event)
            value = app.TrapziosSwitch.Value;
            if strcmp(value,'On')
                app.Progressiva2Switch.Value='Off';
                app.Regressiva2Switch.Value='Off';
                app.Progressiva3Switch.Value='Off';
                app.Regressiva3Switch.Value='Off';
                app.Centrada3Switch.Value='Off';
                app.SimpsonSwitch.Value='Off';
                app.D2DerivadaSwitch.Value='Off';
            end
        end

        % Value changed function: SimpsonSwitch
        function SimpsonSwitchValueChanged(app, event)
            value = app.SimpsonSwitch.Value;
            if strcmp(value,'On')
                app.Progressiva2Switch.Value='Off';
                app.Regressiva2Switch.Value='Off';
                app.Progressiva3Switch.Value='Off';
                app.Regressiva3Switch.Value='Off';
                app.Centrada3Switch.Value='Off';
                app.TrapziosSwitch.Value='Off';
                app.D2DerivadaSwitch.Value='Off';
            end
        end

        % Value changed function: AreaSwitch
        function AreaSwitchValueChanged(app, event)
            value = app.AreaSwitch.Value;
            if strcmp(value,'On')
                app.Progressiva2Switch.Value='Off';
                app.Regressiva2Switch.Value='Off';
                app.Progressiva3Switch.Value='Off';
                app.Regressiva3Switch.Value='Off';
                app.Centrada3Switch.Value='Off';
                app.D2DerivadaSwitch.Value='Off';
            end
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            P=exist("Análise Matemática II - TP3.pdf","file");
            if P==2                
                open("Análise Matemática II - TP3.pdf")
                
            else
                errordlg('O ficheiro não está disponivel',...
                                 'ERRO','modal');
            end
            
        end

        % Menu selected function: yfxMenu
        function yfxMenuSelected(app, event)
             
            %Exporcação de Dados para Excel
            S=questdlg("Quer exportar os dados da tabela para excel","Excel","Sim","Não","Sim");
           
            E=exist("tabela1variaveis.xlsx","file");
           %Caso Exista o ficheiro a função devolve 2.
           
           
            if strcmp(S,"Sim")
                if E==2
                    SN=questdlg("Já existe um ficheiro com o nome tabela1variavel, pretende criar um novo?","Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                        %Apaga o ficheiro existente
                        delete tabela1variavel.xlsx;
                        %Exportação dos dados da UITable com os nomes das Colunas
                        %para um ficheiro Excel que será criado na pasta do
                        %diretório do matlab
                        t=app.UITable.Data;
                        A = table2array(t);
                        T=array2table(A);
                        T.Properties.VariableNames=app.UITable.ColumnName(1:end);
                        filename = 'tabela1variavel.xlsx';
                        writetable(T,filename,'Sheet',1,'Range','B2','WriteVariableNames',true)
                        %Mensagem de Sucesso na criação do ficheiro
                        msgbox('O ficheiro foi criado com sucesso','Successo')
                        %Abertura automática do ficheiro
                        winopen('tabela1variaveis.xlsx');
                    end
                else

                    t=app.UITable.Data;
                    A = table2array(t);
                    T=array2table(A);
                    T.Properties.VariableNames=app.UITable.ColumnName(1:end);
                    filename = 'tabela1variaveis.xlsx';
                    writetable(T,filename,'Sheet',1,'Range','B2','WriteVariableNames',true)                    
                    msgbox('O ficheiro foi criado com sucesso','Successo')                    
                    winopen('tabela1variaveis.xlsx');
                    
                end


            end
        end

        % Menu selected function: PDFMenu
        function PDFMenuSelected(app, event)
              E=exist("grafico1variavel.pdf","file");
              if E==2
                    SN=questdlg("Já existe um ficheiro com o nome grafico1variavel em formato .pdf, pretende criar um novo?"...
                               ,"Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                       exportgraphics(app.UIAxes,'grafico1variavel.pdf')
                       msgbox('O ficheiro foi criado com sucesso','Successo')
                       winopen('grafico1variavel.pdf')
                    end
              else
                    exportgraphics(app.UIAxes,'grafico1variavel.pdf')
                    msgbox('O ficheiro foi criado com sucesso','Successo')
                    winopen('grafico1variavel.pdf')
              end
             
        end

        % Menu selected function: JPEGMenu
        function JPEGMenuSelected(app, event)
              E=exist("grafico1variavel.jpeg","file");
              if E==2
                    SN=questdlg("Já existe um ficheiro com o nome grafico1variavel em formato .jpeg, pretende criar um novo?"...
                               ,"Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                       exportgraphics(app.UIAxes,'grafico1variavel.jpeg')
                       msgbox('O ficheiro foi criado com sucesso','Successo')
                       winopen('grafico1variavel.jpeg')
                    end
              else
                    exportgraphics(app.UIAxes,'grafico1variavel.jpeg')
                    msgbox('O ficheiro foi criado com sucesso','Successo')
                    winopen('grafico1variavel.jpeg')
              end
             
        end

        % Menu selected function: PNGMenuZ
        function PNGMenuZSelected(app, event)
              E=exist("grafico2variavel.png","file");
              if E==2
                    SN=questdlg("Já existe um ficheiro com o nome grafico2variavel em formato .png, pretende criar um novo?"...
                               ,"Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                       exportgraphics(app.UIGrafico2v,'grafico2variavel.png')
                       msgbox('O ficheiro foi criado com sucesso','Successo')
                       winopen('grafico2variavel.png')
                    end
              else
                    exportgraphics(app.UIGrafico2v,'grafico2variavel.png')
                    msgbox('O ficheiro foi criado com sucesso','Successo')
                    winopen('grafico2variavel.png')
              end  
             
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
              E=exist("grafico1variavel.png","file");
              if E==2
                    SN=questdlg("Já existe um ficheiro com o nome grafico1variavel em formato .png, pretende criar um novo?"...
                               ,"Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                       exportgraphics(app.UIAxes,'grafico1variavel.png')
                       msgbox('O ficheiro foi criado com sucesso','Successo')
                       winopen('grafico1variavel.png')
                    end
              else
                    exportgraphics(app.UIAxes,'grafico1variavel.png')
                    msgbox('O ficheiro foi criado com sucesso','Successo')
                    winopen('grafico1variavel.png')
              end
        end

        % Menu selected function: PDFMenuZ
        function PDFMenuZSelected(app, event)
              E=exist("grafico2variavel.pdf","file");
              if E==2
                    SN=questdlg("Já existe um ficheiro com o nome grafico2variavel em formato .pdf, pretende criar um novo?"...
                               ,"Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                       exportgraphics(app.UIGrafico2v,'grafico2variavel.pdf')
                       msgbox('O ficheiro foi criado com sucesso','Successo')
                       winopen('grafico2variavel.pdf')
                    end
              else
                    exportgraphics(app.UIGrafico2v,'grafico2variavel.pdf')
                    msgbox('O ficheiro foi criado com sucesso','Successo')
                    winopen('grafico2variavel.pdf')
              end  
             
        
        end

        % Menu selected function: JPEGMenuZ
        function JPEGMenuZSelected(app, event)
              E=exist("grafico2variavel.jpeg","file");
              if E==2
                    SN=questdlg("Já existe um ficheiro com o nome grafico2variavel em formato .jpeg, pretende criar um novo?"...
                               ,"Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                       exportgraphics(app.UIGrafico2v,'grafico2variavel.jpeg')
                       msgbox('O ficheiro foi criado com sucesso','Successo')
                       winopen('grafico2variavel.jpeg')
                    end
              else
                    exportgraphics(app.UIGrafico2v,'grafico2variavel.jpeg')
                    msgbox('O ficheiro foi criado com sucesso','Successo')
                    winopen('grafico2variavel.jpeg')
              end  
             
    
        end

        % Value changed function: bOpGraf
        function bOpGrafValueChanged(app, event)
            %value = app.bOpGraf.Value;
            bAtualizar2vButtonPushed(app, [])
        end

        % Value changed function: D2DerivadaSwitch
        function D2DerivadaSwitchValueChanged(app, event)
            value = app.D2DerivadaSwitch.Value;
            if strcmp(value,'On')
                
                app.Progressiva2Switch.Value='Off';
                app.Regressiva2Switch.Value='Off';
                app.Progressiva3Switch.Value='Off';
                app.Regressiva3Switch.Value='Off';
                app.Centrada3Switch.Value='Off';
                app.AreaSwitch.Value='Off';
                app.TrapziosSwitch.Value='Off';
                app.SimpsonSwitch.Value='Off';
                
            end 
        end

        % Menu selected function: PDFCurvasMenu
        function PDFCurvasMenuSelected(app, event)
                        E=exist("curvasdeNivel.pdf","file");
              if E==2
                    SN=questdlg("Já existe um ficheiro com o nome curvasdeNivel em formato .pdf, pretende criar um novo?"...
                               ,"Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                       exportgraphics(app.UICNivel,'curvasdeNivel.pdf')
                       msgbox('O ficheiro foi criado com sucesso','Successo')
                       winopen('curvasdeNivel.pdf')
                    end
              else
                    exportgraphics(app.UICNivel,'curvasdeNivel.pdf')
                    msgbox('O ficheiro foi criado com sucesso','Successo')
                    winopen('curvasdeNivel.pdf')
              end  
             
        end

        % Menu selected function: JPEGCurvasMenu
        function JPEGCurvasMenuSelected(app, event)
              E=exist("curvasdeNivel.jpeg","file");
              if E==2
                    SN=questdlg("Já existe um ficheiro com o nome curvasdeNivel em formato .jpeg, pretende criar um novo?"...
                               ,"Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                       exportgraphics(app.UICNivel,'curvasdeNivel.jpeg')
                       msgbox('O ficheiro foi criado com sucesso','Successo')
                       winopen('curvasdeNivel.jpeg')
                    end
              else
                    exportgraphics(app.UICNivel,'curvasdeNivel.jpeg')
                    msgbox('O ficheiro foi criado com sucesso','Successo')
                    winopen('curvasdeNivel.jpeg')
              end  
             
        end

        % Menu selected function: PNGCurvasMenu
        function PNGCurvasMenuSelected(app, event)
              E=exist("curvasdeNivel.png","file");
              if E==2
                    SN=questdlg("Já existe um ficheiro com o nome curvasdeNivel em formato .png, pretende criar um novo?"...
                               ,"Ficheiro Existente","Sim","Não","Sim");
                    if strcmp(SN,"Sim")
                       exportgraphics(app.UICNivel,'curvasdeNivel.png')
                       msgbox('O ficheiro foi criado com sucesso','Successo')
                       winopen('curvasdeNivel.png')
                    end
              else
                    exportgraphics(app.UICNivel,'curvasdeNivel.png')
                    msgbox('O ficheiro foi criado com sucesso','Successo')
                    winopen('curvasdeNivel.png')
              end  
             
        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            sair(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            sair(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.RelatrioMenu = uimenu(app.Menu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.ExportarDadosMenu = uimenu(app.Menu);
            app.ExportarDadosMenu.Text = 'Exportar Dados';
            app.TabelaMenu = uimenu(app.ExportarDadosMenu);
            app.TabelaMenu.Text = 'Tabela';
            app.yfxMenu = uimenu(app.TabelaMenu);
            app.yfxMenu.MenuSelectedFcn = createCallbackFcn(app, @yfxMenuSelected, true);
            app.yfxMenu.Text = 'y=f(x)';
            app.GrficoMenu = uimenu(app.ExportarDadosMenu);
            app.GrficoMenu.Text = 'Gráfico';
            app.yfxMenu_2 = uimenu(app.GrficoMenu);
            app.yfxMenu_2.Text = 'y = f(x)';
            app.PDFMenu = uimenu(app.yfxMenu_2);
            app.PDFMenu.MenuSelectedFcn = createCallbackFcn(app, @PDFMenuSelected, true);
            app.PDFMenu.Text = 'PDF';
            app.JPEGMenu = uimenu(app.yfxMenu_2);
            app.JPEGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPEGMenuSelected, true);
            app.JPEGMenu.Text = 'JPEG';
            app.PNGMenu = uimenu(app.yfxMenu_2);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.zfxyMenu_2 = uimenu(app.GrficoMenu);
            app.zfxyMenu_2.Text = 'z = f(x,y)';
            app.PDFMenuZ = uimenu(app.zfxyMenu_2);
            app.PDFMenuZ.MenuSelectedFcn = createCallbackFcn(app, @PDFMenuZSelected, true);
            app.PDFMenuZ.Text = 'PDF';
            app.JPEGMenuZ = uimenu(app.zfxyMenu_2);
            app.JPEGMenuZ.MenuSelectedFcn = createCallbackFcn(app, @JPEGMenuZSelected, true);
            app.JPEGMenuZ.Text = 'JPEG';
            app.PNGMenuZ = uimenu(app.zfxyMenu_2);
            app.PNGMenuZ.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuZSelected, true);
            app.PNGMenuZ.Text = 'PNG';
            app.CurvasMenu = uimenu(app.GrficoMenu);
            app.CurvasMenu.Text = 'Curvas';
            app.PDFCurvasMenu = uimenu(app.CurvasMenu);
            app.PDFCurvasMenu.MenuSelectedFcn = createCallbackFcn(app, @PDFCurvasMenuSelected, true);
            app.PDFCurvasMenu.Text = 'PDF';
            app.JPEGCurvasMenu = uimenu(app.CurvasMenu);
            app.JPEGCurvasMenu.MenuSelectedFcn = createCallbackFcn(app, @JPEGCurvasMenuSelected, true);
            app.JPEGCurvasMenu.Text = 'JPEG';
            app.PNGCurvasMenu = uimenu(app.CurvasMenu);
            app.PNGCurvasMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGCurvasMenuSelected, true);
            app.PNGCurvasMenu.Text = 'PNG';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = 'Função real de variável real y = f(x)';
            app.pDados = uipanel(app.tgFrVr);
            app.Panel = uipanel(app.pDados);
            app.yxLabel = uilabel(app.Panel);
            app.yxLabel.Text = 'y(x) =';
            app.eF = uieditfield(app.Panel, 'text');
            app.eF.Value = 'sin(x)';
            app.aLabel = uilabel(app.Panel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eH = uieditfield(app.Panel, 'numeric');
            app.eH.Value = 0.1;
            app.hLabel = uilabel(app.Panel);
            app.hLabel.Text = 'h';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 6.28318530717959;
            app.bLabel = uilabel(app.Panel);
            app.bLabel.Text = 'b';
            app.IntervaloLabel = uilabel(app.Panel);
            app.IntervaloLabel.Text = 'Intervalo';
            app.TabGroup2 = uitabgroup(app.pDados);
            app.DerivaoTab = uitab(app.TabGroup2);
            app.DerivaoTab.Title = 'Derivação';
            app.pMetodos_2 = uipanel(app.DerivaoTab);
            app.MetodosNumericosLabel_2 = uilabel(app.pMetodos_2);
            app.MetodosNumericosLabel_2.Text = 'Difereças finitas em 2 Pontos';
            app.ProgressivaSwitch_2Label = uilabel(app.pMetodos_2);
            app.ProgressivaSwitch_2Label.Text = 'Progressiva';
            app.Progressiva2Switch = uiswitch(app.pMetodos_2, 'slider');
            app.Progressiva2Switch.ValueChangedFcn = createCallbackFcn(app, @Progressiva2SwitchValueChanged, true);
            app.RegressivaSwitch_2Label = uilabel(app.pMetodos_2);
            app.RegressivaSwitch_2Label.Text = 'Regressiva';
            app.Regressiva2Switch = uiswitch(app.pMetodos_2, 'slider');
            app.Regressiva2Switch.ValueChangedFcn = createCallbackFcn(app, @Regressiva2SwitchValueChanged, true);
            app.DerivadaLabel = uilabel(app.pMetodos_2);
            app.DerivadaLabel.Text = '2 Derivada';
            app.D2DerivadaSwitch = uiswitch(app.pMetodos_2, 'slider');
            app.D2DerivadaSwitch.ValueChangedFcn = createCallbackFcn(app, @D2DerivadaSwitchValueChanged, true);
            app.DerivaoTab_2 = uitab(app.TabGroup2);
            app.DerivaoTab_2.Title = 'Derivação';
            app.pMetodos = uipanel(app.DerivaoTab_2);
            app.MetodosNumericosLabel = uilabel(app.pMetodos);
            app.MetodosNumericosLabel.Text = 'Difereças finitas em 3 Pontos';
            app.ProgressivaSwitch_3Label = uilabel(app.pMetodos);
            app.ProgressivaSwitch_3Label.Text = 'Progressiva';
            app.Progressiva3Switch = uiswitch(app.pMetodos, 'slider');
            app.Progressiva3Switch.ValueChangedFcn = createCallbackFcn(app, @Progressiva3SwitchValueChanged, true);
            app.RegressivaSwitch_3Label = uilabel(app.pMetodos);
            app.RegressivaSwitch_3Label.Text = 'Regressiva';
            app.Regressiva3Switch = uiswitch(app.pMetodos, 'slider');
            app.Regressiva3Switch.ValueChangedFcn = createCallbackFcn(app, @Regressiva3SwitchValueChanged, true);
            app.CentradaSwitchLabel = uilabel(app.pMetodos);
            app.CentradaSwitchLabel.Text = 'Centrada';
            app.Centrada3Switch = uiswitch(app.pMetodos, 'slider');
            app.Centrada3Switch.ValueChangedFcn = createCallbackFcn(app, @Centrada3SwitchValueChanged, true);
            app.IntegraoTab = uitab(app.TabGroup2);
            app.IntegraoTab.Title = 'Integração';
            app.pMetodos_3 = uipanel(app.IntegraoTab);
            app.MetodosNumericosLabel_3 = uilabel(app.pMetodos_3);
            app.MetodosNumericosLabel_3.Text = 'Integração Númerica';
            app.TrapziosSwitch_2Label = uilabel(app.pMetodos_3);
            app.TrapziosSwitch_2Label.Text = 'Trapézios';
            app.TrapziosSwitch = uiswitch(app.pMetodos_3, 'slider');
            app.TrapziosSwitch.ValueChangedFcn = createCallbackFcn(app, @TrapziosSwitchValueChanged, true);
            app.SimpsonSwitch_2Label = uilabel(app.pMetodos_3);
            app.SimpsonSwitch_2Label.Text = 'Simpson';
            app.SimpsonSwitch = uiswitch(app.pMetodos_3, 'slider');
            app.SimpsonSwitch.ValueChangedFcn = createCallbackFcn(app, @SimpsonSwitchValueChanged, true);
            app.AreaLabel = uilabel(app.pMetodos_3);
            app.AreaLabel.Text = 'Area';
            app.AreaSwitch = uiswitch(app.pMetodos_3, 'slider');
            app.AreaSwitch.ValueChangedFcn = createCallbackFcn(app, @AreaSwitchValueChanged, true);
            app.IntegralLabel = uilabel(app.IntegraoTab);
            app.IntegralLabel.Text = 'Integral';
            app.eExata = uieditfield(app.IntegraoTab, 'numeric');
            app.eRegras = uieditfield(app.IntegraoTab, 'numeric');
            app.RegrasLabel = uilabel(app.IntegraoTab);
            app.RegrasLabel.Text = '     Regras';
            app.DadosLabel = uilabel(app.pDados);
            app.DadosLabel.Text = 'Dados ';
            app.bAtualizar = uibutton(app.pDados, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.UIMostrador = uiaxes(app.pDados);
            app.UIMostrador.XTick = [];
            app.UIMostrador.YTick = [];
            app.pResultados = uipanel(app.tgFrVr);
            app.UITable = uitable(app.pResultados);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.pResultados);
            title(app.UIAxes, 'Gráfico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.tgFr2Vr = uitab(app.TabGroup);
            app.tgFr2Vr.Title = 'Função real de 2 variáveis reais z = f(x,y)';
            app.pDados2v = uipanel(app.tgFr2Vr);
            app.Panel2v = uipanel(app.pDados2v);
            app.fxyLabel = uilabel(app.Panel2v);
            app.fxyLabel.Text = 'f(x,y) = ';
            app.eFxy = uieditfield(app.Panel2v, 'text');
            app.eFxy.Value = 'x^2+y^2';
            app.axLabel = uilabel(app.Panel2v);
            app.axLabel.Text = 'ax';
            app.eAx = uieditfield(app.Panel2v, 'numeric');
            app.eAx.Value = -2;
            app.hxLabel = uilabel(app.Panel2v);
            app.hxLabel.Text = 'hx';
            app.eHx = uieditfield(app.Panel2v, 'numeric');
            app.eHx.Value = 0.1;
            app.bxLabel = uilabel(app.Panel2v);
            app.bxLabel.Text = 'bx';
            app.eBx = uieditfield(app.Panel2v, 'numeric');
            app.eBx.Value = 2;
            app.ayLabel = uilabel(app.Panel2v);
            app.ayLabel.Text = 'ay';
            app.eAy = uieditfield(app.Panel2v, 'numeric');
            app.eAy.Value = -2;
            app.hyLabel = uilabel(app.Panel2v);
            app.hyLabel.Text = 'hy';
            app.eHy = uieditfield(app.Panel2v, 'numeric');
            app.eHy.Value = 0.1;
            app.byLabel = uilabel(app.Panel2v);
            app.byLabel.Text = 'by';
            app.eBy = uieditfield(app.Panel2v, 'numeric');
            app.eBy.Value = 2;
            app.IntegralLabel_2 = uilabel(app.pDados2v);
            app.IntegralLabel_2.Text = 'Integral';
            app.eR2v = uieditfield(app.pDados2v, 'numeric');
            app.bAtualizar2v = uibutton(app.pDados2v, 'push');
            app.bAtualizar2v.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar2vButtonPushed, true);
            app.bAtualizar2v.Text = 'Atualizar';
            app.eHarmonica = uieditfield(app.pDados2v, 'text');
            app.OpesdeClculoKnobLabel = uilabel(app.pDados2v);
            app.OpesdeClculoKnobLabel.Text = 'Opções de Cálculo';
            app.bOpCalc = uiknob(app.pDados2v, 'discrete');
            app.bOpCalc.Items = {'Gráficos', 'Derivada ordem a x', 'Derivada ordem a y', 'Integral duplo'};
            app.bOpCalc.Value = 'Gráficos';
            app.bOpGraf = uiswitch(app.pDados2v, 'slider');
            app.bOpGraf.Items = {'Malha', 'Superfície'};
            app.bOpGraf.ValueChangedFcn = createCallbackFcn(app, @bOpGrafValueChanged, true);
            app.bOpGraf.Value = 'Malha';
            app.UIMostrador2v = uiaxes(app.pDados2v);
            app.UIMostrador2v.XTick = [];
            app.UIMostrador2v.YTick = [];
            app.pResultados2v = uipanel(app.tgFr2Vr);
            app.UIGrafico2v = uiaxes(app.pResultados2v);
            title(app.UIGrafico2v, 'Gráfico')
            xlabel(app.UIGrafico2v, 'X')
            ylabel(app.UIGrafico2v, 'Y')
            zlabel(app.UIGrafico2v, 'Z')
            app.UICNivel = uiaxes(app.pResultados2v);
            title(app.UICNivel, 'Curvas de Nível')
            xlabel(app.UICNivel, 'X')
            ylabel(app.UICNivel, 'Y')
            zlabel(app.UICNivel, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquiaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end